classdef audio_processing_project < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AudioFiltererUIFigure      matlab.ui.Figure
        Image2                     matlab.ui.control.Image
        ScrubberValue              matlab.ui.control.NumericEditField
        Scrubber                   matlab.ui.control.Slider
        BestSoundingPresetButton   matlab.ui.control.Button
        SaveButton                 matlab.ui.control.Button
        Image                      matlab.ui.control.Image
        BlockLengthEditFieldLabel  matlab.ui.control.Label
        BlockLengthEditField       matlab.ui.control.NumericEditField
        BassGain                   matlab.ui.control.NumericEditField
        TrebGain                   matlab.ui.control.NumericEditField
        MidGain                    matlab.ui.control.NumericEditField
        TrebLabel                  matlab.ui.control.Label
        TrebSlider                 matlab.ui.control.Slider
        MidLabel                   matlab.ui.control.Label
        MidSlider                  matlab.ui.control.Slider
        BassLabel                  matlab.ui.control.Label
        BassSlider                 matlab.ui.control.Slider
        VolumeKnob                 matlab.ui.control.Knob
        VolumeKnobLabel            matlab.ui.control.Label
        CutoffHi                   matlab.ui.control.NumericEditField
        CutoffFreqHiSlider         matlab.ui.control.Slider
        CutoffFreqHiSliderLabel    matlab.ui.control.Label
        CutoffLo                   matlab.ui.control.NumericEditField
        CutoffFreqLoSlider         matlab.ui.control.Slider
        CutoffFreqLoSliderLabel    matlab.ui.control.Label
        GraphTypeButtonGroup       matlab.ui.container.ButtonGroup
        TimeButton                 matlab.ui.control.RadioButton
        FrequencyButton            matlab.ui.control.RadioButton
        EditField                  matlab.ui.control.EditField
        PlaybackControlsPanel      matlab.ui.container.Panel
        ButtonStop                 matlab.ui.control.Button
        ButtonPause                matlab.ui.control.Button
        ButtonPlay                 matlab.ui.control.Button
        GetaudiofileButton         matlab.ui.control.Button
        Image3                     matlab.ui.control.Image
        UIAxes                     matlab.ui.control.UIAxes
        UIAxes_2                   matlab.ui.control.UIAxes
    end


    properties (Access = private)
        selected_file % Full path and name of selected audio file
        x % Input audio
        sample_rate % Sampling rate of audio
        num_channels % Number of channels. Same as number of columns in x
        blk_len = 2048 % Length of each processing block. Adjustable
        state % State of the player (playing, paused or stopped)
        pause_location = 1 % Block location at point where pause button has been pressed
        line_list = line(); % List of lines for different channels for time-domain graphing.
        color_list % List of randomly generated colors for each channel. Keeps it consistent within the same session
        change_detected % Change in graph type detected. Used to redraw the axes without having to redraw it every block
        volume = 10 % Well, it's the uh, volume
        y % Filtered audio. Stored for saving the song
        change_in_filter = false % Checking for a change in filter. Placeholder/Future plans for now
    end

    methods (Access = private)

        function load_song(app)

            % Checking if the user selected a file
            if app.EditField.Value == "" || app.EditField.Value == "You gotta drop something in here"
                app.EditField.Value = "You gotta drop something in here";
                return;
            end

            % Loading the song
            [app.x, app.sample_rate] = audioread(app.selected_file);
            setScrubber(app);

            % Check number of channels
            app.num_channels = size(app.x, 2);

            %app.x = mean(app.x, 2);

            % Making the size of X to be equal to a multiple of blk_len so
            % the app doesn't break while looping through the blocks
            app.x = padarray(app.x, app.blk_len - rem(length(app.x), app.blk_len), 0, 'post');

            % Initializing y, which is used in the actual playing of the
            % song and also for saving it
            app.y = zeros([length(app.x) + app.blk_len/2 + 1, app.num_channels]);

            % Enabling the slider for the song
            app.Scrubber.Enable = true;


        end

        function play_song(app)

            % Closing msound before opening it so there's no way the msound
            % is already open error occurs
            msound('close');
            msound('openwrite', 0, app.sample_rate, app.blk_len, app.num_channels);


            k = (0:app.blk_len/2);
            w = 2*pi*k/app.blk_len;
            f = w*app.sample_rate/(2*pi);

            % Reading the video for the easter egg
            obj = VideoReader("Blend W.mp4");
            idxvid = 1;

            try
                for idx = app.pause_location:app.blk_len:length(app.x)
                    idxvid = idxvid+app.blk_len;

                    % Incrementing the scrubber's value every block
                    app.Scrubber.Value = app.Scrubber.Value + (app.blk_len/app.sample_rate);
                    app.ScrubberValue.Value = app.ScrubberValue.Value + (app.blk_len/app.sample_rate);


                    % States of the app and resetting the pause location
                    if (app.pause_location ~= 1)
                        app.pause_location = 1;
                    end
                    if (app.state == "stopped")
                        app.pause_location = 1;
                        break;
                    end
                    if (app.state == "paused")
                        app.pause_location = idx;
                        break;
                    end

                    % Constructing the filter
                    h = (app.BassGain.Value * lowPassDesigner(app)) +...
                        (app.MidGain.Value * bandPassDesigner(app)) +...
                        (app.TrebGain.Value * highPassDesigner(app));

                    % Making the block to process
                    x_blk = app.x(idx: idx+app.blk_len-1, :);

                    % Setting up the other Easter egg. Now easily
                    % accessible with the "Best Sounding Preset" button
                    if (app.volume >= 30 && ...
                            app.CutoffLo.Value > 600 && ...
                            app.CutoffLo.Value < 1200 && ...
                            app.BassGain.Value >= 10)
                        app.volume = 300;
                        h = 15 * lowPassDesigner(app);
                        app.change_detected = true;
                    else
                        app.volume = app.VolumeKnob.Value;
                    end

                    % Setting up the video easter egg. Switch the frame
                    % according to the framerate
                    if(hasFrame(obj) && ...
                            idxvid > 1/obj.FrameRate && ...
                            app.selected_file == "japanese_culture&snoop_dogg.mp3")
                        app.UIAxes_2.Visible = true;
                        idxvid = 1;
                        vidFrame = readFrame(obj);
                        image(vidFrame, 'Parent', app.UIAxes_2)
                        set(app.UIAxes_2, 'Visible', 'off');
                    else
                        app.UIAxes_2.Visible = false;
                    end

                    % Ignore the filter and play as-is if h = 0 or
                    % FFT filter the block if h has a value
                    if(h == 0)
                        y_blk = x_blk;
                        app.y(idx: idx+app.blk_len-1, :) = x_blk;
                    else
                        h = h';
                        target_length = length(x_blk) + length(h);

                        % Processing the input signal this way allows for
                        % any number of channels, not just stereo or mono
                        for i = 1:app.num_channels
                            X_blk(:, i) = fft(x_blk(:, i), target_length);
                            H = fft(h, target_length);
                            Y = X_blk(:, i).*H;
                            temp = ifft(Y);

                            % Storing the filtered result directly in y
                            % instead of y_blk. This makes it easier to
                            % export the edited song
                            app.y(idx: idx+target_length-1, i) = app.y(idx: idx+target_length-1, i) + temp;
                            y_blk(:, i) = app.y(idx: idx+app.blk_len-1, i);
                        end
                    end

                    % Playing the block with msound
                    msound('putsamples', 0.1*app.volume*y_blk)

                    % Show the hidden axes if either of the easter egg's
                    % conditions have been met
                    if ((app.volume >= 30 && ...
                            app.CutoffLo.Value > 600 && ...
                            app.CutoffLo.Value < 1200 && ...
                            app.BassGain.Value >= 10) || ...
                            (hasFrame(obj) && ...
                            idxvid > 1/obj.FrameRate && ...
                            app.selected_file == "japanese_culture&snoop_dogg.mp3"))
                        set(app.UIAxes, 'Color', 'None');
                    else
                        set(app.UIAxes, 'Color', 'white')
                    end

                    % Run this part on first iteration or when graph type
                    % changes
                    if (idx == 1 || app.change_detected == true)
                        app.change_detected = false;
                        cla(app.UIAxes);
                        if (app.volume >= 30 && ...
                                app.CutoffLo.Value > 600 && ...
                                app.CutoffLo.Value < 1200 && ...
                                app.BassGain.Value >= 10)
                            app.UIAxes_2.Visible = true;
                            imagesc(app.UIAxes_2, imread("deepfried.jpg"));
                        else
                            app.UIAxes_2.Visible = false;
                        end

                        % Randomize colors for each audio channel
                        for i = 1:app.num_channels

                            if (isempty(app.color_list))
                                color = colorRandomizer(app);
                                app.color_list(1, :) = color;

                            elseif (size(app.color_list, 1)+1 == i)
                                color = colorRandomizer(app);
                                app.color_list(i, :) = color;
                            end

                            % Frequency graph initializer
                            if (app.GraphTypeButtonGroup.SelectedObject == app.FrequencyButton)
                                Y_blk = fft(y_blk);
                                Y_blk_half = 20*log10(abs(Y_blk(k+1)));
                                semilogx(app.UIAxes, f, Y_blk_half)
                                set(app.UIAxes,...
                                    'XLim', [50 app.sample_rate/2], 'XLimMode', 'manual',...
                                    'YLim', [0 60], 'YLimMode', 'manual',...
                                    'XTickMode', 'auto', 'XTickLabelMode', 'auto');

                                % Time graph initializer
                            elseif (app.GraphTypeButtonGroup.SelectedObject == app.TimeButton)
                                app.line_list(i) = line(app.UIAxes,...
                                    'XData', 0: app.blk_len - 1,...
                                    'YData', y_blk(:, i),...
                                    'Color', app.color_list(i, :)/255,...
                                    'LineWidth', 1);
                                set(app.UIAxes,...
                                    'XLim', [0 app.blk_len-1], 'XLimMode', 'manual',...
                                    'YLim', [-1 1], 'YLimMode', 'manual',...
                                    'XTickMode', 'auto', 'XTickLabelMode', 'auto',...
                                    'XScale', 'linear');
                            end
                        end
                    else
                        % Frequency and time graph changes per block
                        for i = 1:app.num_channels
                            if (app.GraphTypeButtonGroup.SelectedObject == app.FrequencyButton)
                                Y_blk = fft(y_blk);
                                Y_blk_half = 20*log10(abs(Y_blk(k+1)));
                                semilogx(app.UIAxes, f, Y_blk_half)
                            elseif (app.GraphTypeButtonGroup.SelectedObject == app.TimeButton)
                                app.line_list(i).YData = y_blk(:, i);
                            end
                        end
                    end
                    drawnow limitrate;
                end
                % Allow user to edit the block length again
                if (app.state ~= "paused")
                    app.BlockLengthEditField.Editable = true;
                end
            catch e
                msound('close');
            end
            msound('close');
        end

        function arr = colorRandomizer(app)
            for i = 1:app.num_channels

                red = randi(255);
                green = randi(255);
                blue = randi(255);
                arr = [red, green, blue];

            end
        end

        function h_lo = lowPassDesigner(app)

            wc = 2*pi*app.CutoffLo.Value/app.sample_rate;
            if (app.BassGain.Value ~= 0)
                h_lo = fir1(app.blk_len/2, wc/pi, 'low');
            else
                h_lo = 0;
            end
        end

        function h_mid = bandPassDesigner(app)

            wc_lo = 2*pi*app.CutoffLo.Value/app.sample_rate;
            wc_hi = 2*pi*app.CutoffHi.Value/app.sample_rate;
            if (app.MidGain.Value ~= 0)
                h_mid = fir1(app.blk_len/2, [wc_lo/pi wc_hi/pi], 'bandpass');
            else
                h_mid = 0;
            end

        end

        function h_hi = highPassDesigner(app)

            wc = 2*pi*app.CutoffHi.Value/app.sample_rate;
            if (app.TrebGain.Value ~= 0)
                h_hi = fir1(app.blk_len/2, wc/pi, 'high');
            else
                h_hi = 0;
            end

        end

        function setScrubber(app)
            app.Scrubber.Limits = [0, (length(app.x)/app.sample_rate)];
            app.ScrubberValue.Limits = [0, (length(app.x)/app.sample_rate)];
            app.Scrubber.Value = 0;
            app.ScrubberValue.Value = 0;

        end

        function play_blend_w(app)
            obj = VideoReader("Blend W.mp4");
            disp("Function reached")
            while hasFrame(obj)
                vidFrame = readFrame(obj);
                image(vidFrame, 'Parent', app.UIAxes_2)
                set(app.UIAxes_2, 'Visible', 'off');
                pause(1/obj.FrameRate);
            end

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            msound('close')
            cla(app.UIAxes)
            close();
        end

        % Button pushed function: GetaudiofileButton
        function GetaudiofileButtonPushed(app, event)
            [app.selected_file, path] = uigetfile({'*.wav;*.mp3;*.ogg'}, 'Select audio file to open.');
            app.EditField.Value = strcat(path, app.selected_file);
            load_song(app);
        end

        % Button pushed function: ButtonPlay
        function ButtonPlayPushed(app, event)
            app.BlockLengthEditField.Editable = false;
            if app.EditField.Value == "" || app.EditField.Value == "You gotta drop something in here"
                app.EditField.Value = "You gotta drop something in here";
            else
                app.state = "playing";
                play_song(app);
            end
        end

        % Button pushed function: ButtonPause
        function ButtonPausePushed(app, event)
            app.state = "paused";
        end

        % Button pushed function: ButtonStop
        function ButtonStopPushed(app, event)
            app.state = "stopped";
            app.pause_location = 1;
            app.BlockLengthEditField.Editable = true;
            app.Scrubber.Value = 0;
            app.ScrubberValue.Value = 0;
        end

        % Value changing function: CutoffFreqLoSlider
        function CutoffFreqLoSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CutoffLo.Value = changingValue;
        end

        % Value changed function: CutoffLo
        function CutoffLoValueChanged(app, event)
            value = app.CutoffLo.Value;
            app.CutoffFreqLoSlider.Value = value;
        end

        % Value changing function: CutoffFreqHiSlider
        function CutoffFreqHiSliderValueChanging(app, event)
            changingValue = event.Value;
            app.CutoffHi.Value = changingValue;
        end

        % Value changed function: CutoffHi
        function CutoffHiValueChanged(app, event)
            value = app.CutoffHi.Value;
            app.CutoffFreqHiSlider.Value = value;
        end

        % Value changing function: BassSlider
        function BassSliderValueChanging(app, event)
            changingValue = event.Value;
            app.BassGain.Value = changingValue;
            app.change_in_filter = true;
        end

        % Value changing function: MidSlider
        function MidSliderValueChanging(app, event)
            changingValue = event.Value;
            app.MidGain.Value = changingValue;
            app.change_in_filter = true;
        end

        % Value changing function: TrebSlider
        function TrebSliderValueChanging(app, event)
            changingValue = event.Value;
            app.TrebGain.Value = changingValue;
            app.change_in_filter = true;
        end

        % Value changed function: BassGain
        function BassGainValueChanged(app, event)
            value = app.BassGain.Value;
            app.BassSlider.Value = value;
            app.change_in_filter = true;
        end

        % Value changed function: MidGain
        function MidGainValueChanged(app, event)
            value = app.MidGain.Value;
            app.MidSlider.Value = value;
            app.change_in_filter = true;
        end

        % Value changed function: TrebGain
        function TrebGainValueChanged(app, event)
            value = app.TrebGain.Value;
            app.TrebSlider.Value = value;
            app.change_in_filter = true;
        end

        % Value changing function: VolumeKnob
        function VolumeKnobValueChanging(app, event)
            changingValue = event.Value;
            app.volume = changingValue;
        end

        % Selection changed function: GraphTypeButtonGroup
        function GraphTypeButtonGroupSelectionChanged(app, event)
            app.change_detected = true;
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.blk_len = changingValue;
        end

        % Image clicked function: Image
        function ImageClicked(app, event)
            msgbox("Change the block length of the processed audio. In most cases increasing it can make it faster, in a few cases reducing can make it faster. Try and see which works for you. This can only be changed when the song is stopped.")
        end

        % Value changed function: BlockLengthEditField
        function BlockLengthEditFieldValueChanged(app, event)
            value = app.BlockLengthEditField.Value;
            app.blk_len = value;
        end

        % Button pushed function: BestSoundingPresetButton
        function BestSoundingPresetButtonPushed(app, event)
            app.VolumeKnob.Value = 30;
            app.BassGain.Value = 10;
            app.BassSlider.Value = 10;
            app.CutoffLo.Value = 800;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            for i = 1:app.num_channels
                temp(i) = find(app.y(:, i), 1, 'last');
            end
            audiowrite(uiputfile({'*.wav'}), app.y(1:max(temp), :), app.sample_rate)
        end

        % Value changed function: Scrubber
        function ScrubberValueChanged(app, event)
            value = app.Scrubber.Value;
            if (app.state == "paused")
                app.pause_location = value * app.sample_rate;
            else
                app.state = "paused";
                app.ScrubberValue.Value = value;
                app.pause_location = value * app.sample_rate;
                app.state = "playing";
                play_song(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AudioFiltererUIFigure = uifigure('Visible', 'off');
            app.AudioFiltererUIFigure.Name = 'Audio Filterer';
            app.AudioFiltererUIFigure.Icon = 'the_greatest_image_of_all_time.png';
            app.UIAxes_2 = uiaxes(app.AudioFiltererUIFigure);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.UIAxes = uiaxes(app.AudioFiltererUIFigure);
            app.UIAxes.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Image3 = uiimage(app.AudioFiltererUIFigure);
            app.Image3.Enable = 'off';
            app.Image3.Visible = 'off';
            app.Image3.ImageSource = 'dsp.png';
            app.GetaudiofileButton = uibutton(app.AudioFiltererUIFigure, 'push');
            app.GetaudiofileButton.ButtonPushedFcn = createCallbackFcn(app, @GetaudiofileButtonPushed, true);
            app.GetaudiofileButton.Text = 'Get audio file';
            app.PlaybackControlsPanel = uipanel(app.AudioFiltererUIFigure);
            app.PlaybackControlsPanel.Title = 'Playback Controls';
            app.ButtonPlay = uibutton(app.PlaybackControlsPanel, 'push');
            app.ButtonPlay.ButtonPushedFcn = createCallbackFcn(app, @ButtonPlayPushed, true);
            app.ButtonPlay.Icon = 'play icon.png';
            app.ButtonPlay.Text = '';
            app.ButtonPause = uibutton(app.PlaybackControlsPanel, 'push');
            app.ButtonPause.ButtonPushedFcn = createCallbackFcn(app, @ButtonPausePushed, true);
            app.ButtonPause.Icon = 'pause icon.png';
            app.ButtonPause.Text = '';
            app.ButtonStop = uibutton(app.PlaybackControlsPanel, 'push');
            app.ButtonStop.ButtonPushedFcn = createCallbackFcn(app, @ButtonStopPushed, true);
            app.ButtonStop.Icon = 'stop icon.jpg';
            app.ButtonStop.Text = '';
            app.EditField = uieditfield(app.AudioFiltererUIFigure, 'text');
            app.EditField.Editable = 'off';
            app.GraphTypeButtonGroup = uibuttongroup(app.AudioFiltererUIFigure);
            app.GraphTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GraphTypeButtonGroupSelectionChanged, true);
            app.GraphTypeButtonGroup.Title = 'Graph Type';
            app.FrequencyButton = uiradiobutton(app.GraphTypeButtonGroup);
            app.FrequencyButton.Text = 'Frequency';
            app.FrequencyButton.Value = true;
            app.TimeButton = uiradiobutton(app.GraphTypeButtonGroup);
            app.TimeButton.Text = 'Time';
            app.CutoffFreqLoSliderLabel = uilabel(app.AudioFiltererUIFigure);
            app.CutoffFreqLoSliderLabel.Text = {'Cutoff'; 'Freq'; '(Lo)'};
            app.CutoffFreqLoSlider = uislider(app.AudioFiltererUIFigure);
            app.CutoffFreqLoSlider.Limits = [1 3000];
            app.CutoffFreqLoSlider.MajorTicks = [];
            app.CutoffFreqLoSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffFreqLoSliderValueChanging, true);
            app.CutoffFreqLoSlider.MinorTicks = [];
            app.CutoffFreqLoSlider.Value = 1000;
            app.CutoffLo = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.CutoffLo.Limits = [1 3000];
            app.CutoffLo.ValueChangedFcn = createCallbackFcn(app, @CutoffLoValueChanged, true);
            app.CutoffLo.Value = 1000;
            app.CutoffFreqHiSliderLabel = uilabel(app.AudioFiltererUIFigure);
            app.CutoffFreqHiSliderLabel.Text = {'Cutoff'; 'Freq'; '(Hi)'};
            app.CutoffFreqHiSlider = uislider(app.AudioFiltererUIFigure);
            app.CutoffFreqHiSlider.Limits = [2500 20000];
            app.CutoffFreqHiSlider.MajorTicks = [];
            app.CutoffFreqHiSlider.ValueChangingFcn = createCallbackFcn(app, @CutoffFreqHiSliderValueChanging, true);
            app.CutoffFreqHiSlider.MinorTicks = [];
            app.CutoffFreqHiSlider.Value = 3000;
            app.CutoffHi = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.CutoffHi.Limits = [2500 20000];
            app.CutoffHi.ValueChangedFcn = createCallbackFcn(app, @CutoffHiValueChanged, true);
            app.CutoffHi.Value = 3000;
            app.VolumeKnobLabel = uilabel(app.AudioFiltererUIFigure);
            app.VolumeKnobLabel.Text = 'Volume';
            app.VolumeKnob = uiknob(app.AudioFiltererUIFigure, 'continuous');
            app.VolumeKnob.Limits = [0 30];
            app.VolumeKnob.MajorTicks = [0 3 6 9 12 15 18 21 24 27 30];
            app.VolumeKnob.ValueChangingFcn = createCallbackFcn(app, @VolumeKnobValueChanging, true);
            app.VolumeKnob.Value = 10;
            app.BassSlider = uislider(app.AudioFiltererUIFigure);
            app.BassSlider.Limits = [0 10];
            app.BassSlider.MajorTicks = [];
            app.BassSlider.ValueChangingFcn = createCallbackFcn(app, @BassSliderValueChanging, true);
            app.BassSlider.MinorTicks = [];
            app.BassLabel = uilabel(app.AudioFiltererUIFigure);
            app.BassLabel.Text = 'Bass';
            app.MidSlider = uislider(app.AudioFiltererUIFigure);
            app.MidSlider.Limits = [0 10];
            app.MidSlider.MajorTicks = [];
            app.MidSlider.ValueChangingFcn = createCallbackFcn(app, @MidSliderValueChanging, true);
            app.MidSlider.MinorTicks = [];
            app.MidLabel = uilabel(app.AudioFiltererUIFigure);
            app.MidLabel.Text = 'Mid';
            app.TrebSlider = uislider(app.AudioFiltererUIFigure);
            app.TrebSlider.Limits = [0 10];
            app.TrebSlider.MajorTicks = [];
            app.TrebSlider.ValueChangingFcn = createCallbackFcn(app, @TrebSliderValueChanging, true);
            app.TrebSlider.MinorTicks = [];
            app.TrebLabel = uilabel(app.AudioFiltererUIFigure);
            app.TrebLabel.Text = 'Treb';
            app.MidGain = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.MidGain.Limits = [0 100];
            app.MidGain.ValueChangedFcn = createCallbackFcn(app, @MidGainValueChanged, true);
            app.TrebGain = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.TrebGain.Limits = [0 100];
            app.TrebGain.ValueChangedFcn = createCallbackFcn(app, @TrebGainValueChanged, true);
            app.BassGain = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.BassGain.Limits = [0 100];
            app.BassGain.ValueChangedFcn = createCallbackFcn(app, @BassGainValueChanged, true);
            app.BlockLengthEditField = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.BlockLengthEditField.Limits = [1 65528];
            app.BlockLengthEditField.ValueChangedFcn = createCallbackFcn(app, @BlockLengthEditFieldValueChanged, true);
            app.BlockLengthEditField.Value = 2048;
            app.BlockLengthEditFieldLabel = uilabel(app.AudioFiltererUIFigure);
            app.BlockLengthEditFieldLabel.Text = {'Block'; 'Length'};
            app.Image = uiimage(app.AudioFiltererUIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = 'hlep.png';
            app.SaveButton = uibutton(app.AudioFiltererUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.BestSoundingPresetButton = uibutton(app.AudioFiltererUIFigure, 'push');
            app.BestSoundingPresetButton.ButtonPushedFcn = createCallbackFcn(app, @BestSoundingPresetButtonPushed, true);
            app.BestSoundingPresetButton.Text = 'Best Sounding Preset';
            app.Scrubber = uislider(app.AudioFiltererUIFigure);
            app.Scrubber.MajorTicks = [];
            app.Scrubber.ValueChangedFcn = createCallbackFcn(app, @ScrubberValueChanged, true);
            app.Scrubber.MinorTicks = [];
            app.Scrubber.Enable = 'off';
            app.ScrubberValue = uieditfield(app.AudioFiltererUIFigure, 'numeric');
            app.ScrubberValue.Editable = 'off';
            app.Image2 = uiimage(app.AudioFiltererUIFigure);
            app.Image2.ImageSource = 'dsp.jpg';
            % Show the figure after all components are created
            app.AudioFiltererUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = audio_processing_project

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AudioFiltererUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AudioFiltererUIFigure)
        end
    end
end